classdef aircraft_sizing_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        AREditFieldLabel        matlab.ui.control.Label
        AREditField             matlab.ui.control.NumericEditField
        CDTOEditFieldLabel      matlab.ui.control.Label
        CDTOEditField           matlab.ui.control.NumericEditField
        CDminEditFieldLabel     matlab.ui.control.Label
        CDminEditField          matlab.ui.control.NumericEditField
        CLTOEditFieldLabel      matlab.ui.control.Label
        CLTOEditField           matlab.ui.control.NumericEditField
        gEditFieldLabel         matlab.ui.control.Label
        gEditField              matlab.ui.control.NumericEditField
        HcruiseEditFieldLabel   matlab.ui.control.Label
        HcruiseEditField        matlab.ui.control.NumericEditField
        HCellingEditFieldLabel  matlab.ui.control.Label
        HCellingEditField       matlab.ui.control.NumericEditField
        nEditFieldLabel         matlab.ui.control.Label
        nEditField              matlab.ui.control.NumericEditField
        ROCEditFieldLabel       matlab.ui.control.Label
        ROCEditField            matlab.ui.control.NumericEditField
        VclimbEditFieldLabel    matlab.ui.control.Label
        VclimbEditField         matlab.ui.control.NumericEditField
        VCEditFieldLabel        matlab.ui.control.Label
        VCEditField             matlab.ui.control.NumericEditField
        VLOFEditFieldLabel      matlab.ui.control.Label
        VLOFEditField           matlab.ui.control.NumericEditField
        SGEditFieldLabel        matlab.ui.control.Label
        SGEditField             matlab.ui.control.NumericEditField
        muEditFieldLabel        matlab.ui.control.Label
        muEditField             matlab.ui.control.NumericEditField
        etaEditFieldLabel       matlab.ui.control.Label
        etaEditField            matlab.ui.control.NumericEditField
        WEditFieldLabel         matlab.ui.control.Label
        WEditField              matlab.ui.control.NumericEditField
        plotButton              matlab.ui.control.Button
        clearButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            WtoS = linspace(5,58);
            rhoTO = 0.002378;
            e = 1.78*(1-(0.045*(app.AREditField.Value)^0.68))-0.64;
            k = 1/(pi*app.AREditField.Value*e);
            Vs = 0.3*app.VCEditField.Value;
            VV = app.ROCEditField.Value/60;
            qcruise = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*app.VCEditField.Value)^2;%(lbf/ft^2)
            qTO = 0.5*rhoTO*((1.688*app.VLOFEditField.Value)/(2)^(1/2))^2;%(lbf/ft^2)
            qclimb = 0.5*rhoTO*(1.688*app.VclimbEditField.Value)^2;%(lbf/ft^2)
            qstall = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*Vs)^2;%(lbf/ft^2)
            CLmax = (1/qstall).*WtoS;
            
            % T/W for a Level Constant-velocity Turn
            TtoW = qcruise*(( app.CDminEditField.Value./WtoS)+(k*(app.nEditField.Value/qcruise)^2).*(WtoS));
            plot(app.UIAxes,WtoS,TtoW)
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XLim = [0 50];
            hold(app.UIAxes,'on')
            
         
            % T/W for a Desired Rate of Climb 
            TtoW = (VV/(1.688*app.VclimbEditField.Value))+((qclimb./WtoS))*app.CDminEditField.Value + ((k/qclimb).*WtoS);
            plot(app.UIAxes,WtoS,TtoW)
            hold(app.UIAxes,'on')
            
            % T/W for a Desired T-O Distance 
            TtoW = (((1.688*app.VLOFEditField.Value)^2)/(2*app.gEditField.Value*app.SGEditField.Value)) + ((qTO*app.CDTOEditField.Value)./WtoS) + app.muEditField.Value.*(1-((qTO*app.CLTOEditField.Value)./WtoS));
            plot(app.UIAxes,WtoS,TtoW)
            hold(app.UIAxes,'on')
            
            % T/W for a Desired Cruise Airspeed
            TtoW = ((qcruise*app.CDminEditField.Value)./WtoS) + (k/qcruise).*WtoS;
            plot(app.UIAxes,WtoS,TtoW)
            hold(app.UIAxes,'on')
            
            % T/W for a Service Ceiling
            TtoW = (1.667./((2./rhocal(app.HCellingEditField.Value)).*WtoS.*(k./(3*app.CDminEditField.Value)).^(1/2)).^(1/2)) + 4*((k*app.CDminEditField.Value)./3).^(1/2);
            plot(app.UIAxes,WtoS,TtoW)
            hold(app.UIAxes,'on')
            
            % CLmax for a Desired Stalling Speed
            yyaxis(app.UIAxes,'right')
            app.UIAxes.YLabel.String='CLMAX';
            app.UIAxes.YLim = [0 5];
            for i = 30:10:70
                qstall = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*i)^2;
                CLmax = (1/qstall).*WtoS;
                %disp(CLmax)
                plot(app.UIAxes,WtoS,CLmax)
           
                SlopeBetweenPoints = diff(CLmax)./diff(WtoS);
                %disp(SlopeBetweenPoints)
                %disp(5.*SlopeBetweenPoints(1))
                %text(1,1,'h');%strcat(int2str(i),' KCAS')
                text(app.UIAxes,double(5),double((5.*SlopeBetweenPoints(1))),strcat(int2str(i),' KCAS'),'FontSize', 5)
                hold(app.UIAxes,'on')
            end
            legend(app.UIAxes,{'Turn Requirement','Climb Requirement','T-O Requirement','Airspeed Requirement','Service Celling','Stall speed'},'Location','northeast')
            
            qstall = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*Vs)^2;
            CLmax = (1/qstall).*WtoS;
            plot(app.UIAxes,WtoS,CLmax)
            SlopeBetweenPoints = diff(CLmax)./diff(WtoS);
            %disp(SlopeBetweenPoints)
            VsText = strcat('VS = ',mat2str(Vs));
            text(app.UIAxes,5,(5.*SlopeBetweenPoints(1)),strcat(VsText,' KCAS'),'FontSize', 5)
            hold(app.UIAxes,'on')
    
            qstall = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*61)^2;
            CLmax = (1/qstall).*WtoS;
            plot(app.UIAxes,WtoS,CLmax)
            SlopeBetweenPoints = diff(CLmax)./diff(WtoS);
            %disp(SlopeBetweenPoints)
            text(app.UIAxes,5,(5.*SlopeBetweenPoints(1)),'VS = 61 KCAS','FontSize',5) %FAR 23 limit
            hold(app.UIAxes,'on')
            legend(app.UIAxes,{'Turn Requirement','Climb Requirement','T-O Requirement','Airspeed Requirement','Service Celling','Stall speed','Stall speed Vs = 45 KCAS(LSA Limit)','Stall speed Vs = 61 KCAS(FAR 21 Limit)'},'Location','northeast')
            hold(app.UIAxes,'off')
            
            % BHP Requirement
            
            TtoW = qcruise*((app.CDminEditField.Value./WtoS)+(k*(app.nEditField.Value/qcruise)^2).*(WtoS));
            PBHP = (TtoW.*app.WEditField.Value.*1.688*app.VCEditField.Value)./(app.etaEditField.Value.*550);
            plot(app.UIAxes2,WtoS,PBHP)
            app.UIAxes2.YLim = [0 1000];
            app.UIAxes2.XLim = [0 50];
   
            hold(app.UIAxes2,'on')
            
            TtoW = (VV/(1.688*app.VclimbEditField.Value))+((qclimb./WtoS))*app.CDminEditField.Value + ((k/qclimb).*WtoS);
            PBHP = (TtoW.*app.WEditField.Value.*1.688*app.VclimbEditField.Value)./(app.etaEditField.Value.*550);
            plot(app.UIAxes2,WtoS,PBHP)
            %legend({'Turn Requirement','Climb Requirement'},'Location','northeast')
            
            hold(app.UIAxes2,'on')
            
            TtoW = (((1.688*app.VLOFEditField.Value)^2)/(2*app.gEditField.Value*app.SGEditField.Value)) + ((qTO*app.CDTOEditField.Value)./WtoS) + app.muEditField.Value.*(1-((qTO*app.CLTOEditField.Value)./WtoS));
            PBHP = (TtoW.*app.WEditField.Value.*1.688*app.VLOFEditField.Value)./(app.etaEditField.Value.*550);
            plot(app.UIAxes2,WtoS,PBHP)
            %legend({'Turn Requirement','Climb Requirement','T-O Requirement'},'Location','northeast')
            
            hold(app.UIAxes2,'on')
            
            TtoW = ((qcruise*app.CDminEditField.Value)./WtoS) + (k/qcruise).*WtoS;
            PBHP = (TtoW.*app.WEditField.Value.*1.688*app.VCEditField.Value)./(app.etaEditField.Value.*550);
            plot(app.UIAxes2,WtoS,PBHP)
            %legend({'Turn Requirement','Climb Requirement','T-O Requirement','Airspeed Requirement'},'Location','northeast')
            
            hold(app.UIAxes2,'on')
            
            TtoW = (1.667./((2./rhocal(app.HCellingEditField.Value)).*WtoS.*(k./(3*app.CDminEditField.Value)).^(1/2)).^(1/2)) + 4*((k*app.CDminEditField.Value)./3).^(1/2);
            PBHP = (TtoW.*app.WEditField.Value.*1.688*app.VCEditField.Value)./(app.etaEditField.Value.*550);
            plot(app.UIAxes2,WtoS,PBHP)
            %legend({'Turn Requirement','Climb Requirement','T-O Requirement','Airspeed Requirement','Service Celling'},'Location','northeast')
            
            hold(app.UIAxes2,'on')
            
            yyaxis(app.UIAxes2,'right')
            app.UIAxes2.YLabel.String='CLMAX';
            app.UIAxes2.YLim = [0 5];
            for i = 30:10:70
                qstall = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*i)^2;
                CLmax = (1/qstall).*WtoS;
                %disp(CLmax)
                plot(app.UIAxes2,WtoS,CLmax)
           
                SlopeBetweenPoints = diff(CLmax)./diff(WtoS);
                %disp(SlopeBetweenPoints)
                %disp(5.*SlopeBetweenPoints(1))
                %text(1,1,'h');%strcat(int2str(i),' KCAS')
                text(app.UIAxes2,double(5),double((5.*SlopeBetweenPoints(1))),strcat(int2str(i),' KCAS'),'FontSize', 5)
                hold(app.UIAxes2,'on')
            end
            legend(app.UIAxes2,{'Turn Requirement','Climb Requirement','T-O Requirement','Airspeed Requirement','Service Celling','Stall speed'},'Location','northeast')
            
            qstall = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*Vs)^2;
            CLmax = (1/qstall).*WtoS;
            plot(app.UIAxes2,WtoS,CLmax)
            SlopeBetweenPoints = diff(CLmax)./diff(WtoS);
            %disp(SlopeBetweenPoints)
            VsText = strcat('VS = ',mat2str(Vs));
            text(app.UIAxes2,5,(5.*SlopeBetweenPoints(1)),strcat(VsText,' KCAS'),'FontSize', 5)
            hold(app.UIAxes2,'on')
    
            qstall = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*61)^2;
            CLmax = (1/qstall).*WtoS;
            plot(app.UIAxes2,WtoS,CLmax)
            SlopeBetweenPoints = diff(CLmax)./diff(WtoS);
            %disp(SlopeBetweenPoints)
            text(app.UIAxes2,5,(5.*SlopeBetweenPoints(1)),'VS = 61 KCAS','FontSize',5) %FAR 23 limit
            hold(app.UIAxes2,'on')
            legend(app.UIAxes2,{'Turn Requirement','Climb Requirement','T-O Requirement','Airspeed Requirement','Service Celling','Stall speed','Stall speed Vs = 45 KCAS(LSA Limit)','Stall speed Vs = 61 KCAS(FAR 21 Limit)'},'Location','northeast')
            hold(app.UIAxes2,'off')
            
            
            %BHP Requirement-Normalized to S-L
            
            TtoW = qcruise*((app.CDminEditField.Value./WtoS)+(k*(app.nEditField.Value/qcruise)^2).*(WtoS));
            PBHP = (TtoW.*app.WEditField.Value.*1.688*app.VCEditField.Value)./(app.etaEditField.Value.*550);
            PBHPSL = PBHP/(1.132*(rhocal(app.HcruiseEditField.Value)/rhoTO)-0.132);
            plot(app.UIAxes3,WtoS,PBHPSL)
            app.UIAxes3.YLim = [0 1000];
            app.UIAxes3.XLim = [0 50];
   
            hold(app.UIAxes3,'on')
            
            TtoW = (VV/(1.688*app.VclimbEditField.Value))+((qclimb./WtoS))*app.CDminEditField.Value + ((k/qclimb).*WtoS);
            PBHP = (TtoW.*app.WEditField.Value.*1.688*app.VclimbEditField.Value)./(app.etaEditField.Value.*550);
            PBHPSL = PBHP/(1.132*(rhoTO/rhoTO)-0.132);
            plot(app.UIAxes3,WtoS,PBHPSL)
            %legend({'Turn Requirement','Climb Requirement'},'Location','northeast')
            
            hold(app.UIAxes3,'on')
            
            TtoW = (((1.688*app.VLOFEditField.Value)^2)/(2*app.gEditField.Value*app.SGEditField.Value)) + ((qTO*app.CDTOEditField.Value)./WtoS) + app.muEditField.Value.*(1-((qTO*app.CLTOEditField.Value)./WtoS));
            PBHP = (TtoW.*app.WEditField.Value.*1.688*app.VLOFEditField.Value)./(app.etaEditField.Value.*550);
            PBHPSL = PBHP/(1.132*(rhoTO/rhoTO)-0.132);
            plot(app.UIAxes3,WtoS,PBHPSL)
            
            %legend({'Turn Requirement','Climb Requirement','T-O Requirement'},'Location','northeast')
            
            hold(app.UIAxes3,'on')
            
            TtoW = ((qcruise*app.CDminEditField.Value)./WtoS) + (k/qcruise).*WtoS;
            PBHP = (TtoW.*app.WEditField.Value.*1.688*app.VCEditField.Value)./(app.etaEditField.Value.*550);
            PBHPSL = PBHP/(1.132*(rhocal(app.HcruiseEditField.Value)/rhoTO)-0.132);
            plot(app.UIAxes3,WtoS,PBHPSL)
            %legend({'Turn Requirement','Climb Requirement','T-O Requirement','Airspeed Requirement'},'Location','northeast')
            
            hold(app.UIAxes3,'on')
            
            TtoW = (1.667./((2./rhocal(app.HCellingEditField.Value)).*WtoS.*(k./(3*app.CDminEditField.Value)).^(1/2)).^(1/2)) + 4*((k*app.CDminEditField.Value)./3).^(1/2);
            PBHP = (TtoW.*app.WEditField.Value.*1.688*app.VCEditField.Value)./(app.etaEditField.Value.*550);
            PBHPSL = PBHP/(1.132*(rhocal(app.HCellingEditField.Value)/rhoTO)-0.132);
            plot(app.UIAxes3,WtoS,PBHPSL)
            %legend({'Turn Requirement','Climb Requirement','T-O Requirement','Airspeed Requirement','Service Celling'},'Location','northeast')
            
            hold(app.UIAxes3,'on')
            
            yyaxis(app.UIAxes3,'right')
            app.UIAxes3.YLabel.String='CLMAX';
            app.UIAxes3.YLim = [0 5];
            for i = 30:10:70
                qstall = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*i)^2;
                CLmax = (1/qstall).*WtoS;
                %disp(CLmax)
                plot(app.UIAxes3,WtoS,CLmax)
           
                SlopeBetweenPoints = diff(CLmax)./diff(WtoS);
                %disp(SlopeBetweenPoints)
                %disp(5.*SlopeBetweenPoints(1))
                %text(1,1,'h');%strcat(int2str(i),' KCAS')
                text(app.UIAxes3,double(5),double((5.*SlopeBetweenPoints(1))),strcat(int2str(i),' KCAS'),'FontSize', 5)
                hold(app.UIAxes3,'on')
            end
            legend(app.UIAxes3,{'Turn Requirement','Climb Requirement','T-O Requirement','Airspeed Requirement','Service Celling','Stall speed'},'Location','northeast')
            
            qstall = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*Vs)^2;
            CLmax = (1/qstall).*WtoS;
            plot(app.UIAxes3,WtoS,CLmax)
            SlopeBetweenPoints = diff(CLmax)./diff(WtoS);
            %disp(SlopeBetweenPoints)
            VsText = strcat('VS = ',mat2str(Vs));
            text(app.UIAxes3,5,(5.*SlopeBetweenPoints(1)),strcat(VsText,' KCAS'),'FontSize', 5)
            hold(app.UIAxes3,'on')
    
            qstall = 0.5*rhocal(app.HcruiseEditField.Value)*(1.688*61)^2;
            CLmax = (1/qstall).*WtoS;
            plot(app.UIAxes3,WtoS,CLmax)
            SlopeBetweenPoints = diff(CLmax)./diff(WtoS);
            %disp(SlopeBetweenPoints)
            text(app.UIAxes3,5,(5.*SlopeBetweenPoints(1)),'VS = 61 KCAS','FontSize',5) %FAR 23 limit
            hold(app.UIAxes3,'on')
            legend(app.UIAxes3,{'Turn Requirement','Climb Requirement','T-O Requirement','Airspeed Requirement','Service Celling','Stall speed','Stall speed Vs = 45 KCAS(LSA Limit)','Stall speed Vs = 61 KCAS(FAR 21 Limit)'},'Location','northeast')
            hold(app.UIAxes3,'off')


        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.UIAxes.cla;
            
           app.UIAxes2.cla;
            app.UIAxes3.cla;
            
            yyaxis(app.UIAxes,'left')
            yyaxis(app.UIAxes2,'left')
            yyaxis(app.UIAxes3,'left')
            app.UIAxes.cla;
            
           app.UIAxes2.cla;
            app.UIAxes3.cla;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Constraint Diagram')
            xlabel(app.UIAxes, 'Wing Loading, W/S')
            ylabel(app.UIAxes, 'Thrust Loading, T/W')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'BHP Requirement')
            xlabel(app.UIAxes2, 'Wing Loading, W/S')
            ylabel(app.UIAxes2, 'Brake-horsepower Requirement,BHP')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'BHP Requirement-Normalized to S-L')
            xlabel(app.UIAxes3, 'Wing Loading, W/S')
            ylabel(app.UIAxes3, 'Brake-horsepower Requirement,BHP')
            app.AREditFieldLabel = uilabel(app.UIFigure);
            app.AREditFieldLabel.Text = 'AR';
            app.AREditField = uieditfield(app.UIFigure, 'numeric');
            app.CDTOEditFieldLabel = uilabel(app.UIFigure);
            app.CDTOEditFieldLabel.Text = 'CDTO';
            app.CDTOEditField = uieditfield(app.UIFigure, 'numeric');
            app.CDminEditFieldLabel = uilabel(app.UIFigure);
            app.CDminEditFieldLabel.Text = 'CDmin';
            app.CDminEditField = uieditfield(app.UIFigure, 'numeric');
            app.CLTOEditFieldLabel = uilabel(app.UIFigure);
            app.CLTOEditFieldLabel.Text = 'CLTO';
            app.CLTOEditField = uieditfield(app.UIFigure, 'numeric');
            app.gEditFieldLabel = uilabel(app.UIFigure);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.UIFigure, 'numeric');
            app.HcruiseEditFieldLabel = uilabel(app.UIFigure);
            app.HcruiseEditFieldLabel.Text = 'Hcruise';
            app.HcruiseEditField = uieditfield(app.UIFigure, 'numeric');
            app.HCellingEditFieldLabel = uilabel(app.UIFigure);
            app.HCellingEditFieldLabel.Text = 'HCelling';
            app.HCellingEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.ROCEditFieldLabel = uilabel(app.UIFigure);
            app.ROCEditFieldLabel.Text = 'ROC';
            app.ROCEditField = uieditfield(app.UIFigure, 'numeric');
            app.VclimbEditFieldLabel = uilabel(app.UIFigure);
            app.VclimbEditFieldLabel.Text = 'Vclimb';
            app.VclimbEditField = uieditfield(app.UIFigure, 'numeric');
            app.VCEditFieldLabel = uilabel(app.UIFigure);
            app.VCEditFieldLabel.Text = 'VC';
            app.VCEditField = uieditfield(app.UIFigure, 'numeric');
            app.VLOFEditFieldLabel = uilabel(app.UIFigure);
            app.VLOFEditFieldLabel.Text = 'VLOF';
            app.VLOFEditField = uieditfield(app.UIFigure, 'numeric');
            app.SGEditFieldLabel = uilabel(app.UIFigure);
            app.SGEditFieldLabel.Text = 'SG';
            app.SGEditField = uieditfield(app.UIFigure, 'numeric');
            app.muEditFieldLabel = uilabel(app.UIFigure);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.UIFigure, 'numeric');
            app.etaEditFieldLabel = uilabel(app.UIFigure);
            app.etaEditFieldLabel.Text = 'eta';
            app.etaEditField = uieditfield(app.UIFigure, 'numeric');
            app.WEditFieldLabel = uilabel(app.UIFigure);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField = uieditfield(app.UIFigure, 'numeric');
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aircraft_sizing_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end